classdef ProductPreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider_2             matlab.ui.control.Slider
        Label_4              matlab.ui.control.Label
        DehydrationButton    matlab.ui.control.Button
        UnevenToneButton     matlab.ui.control.Button
        DullnessButton       matlab.ui.control.Button
        AcneBreakoutsButton  matlab.ui.control.Button
        TextureButton        matlab.ui.control.Button
        PuffinessButton      matlab.ui.control.Button
        AgingButton          matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        LipCareButton        matlab.ui.control.Button
        TreatmentButton      matlab.ui.control.Button
        SPFButton            matlab.ui.control.Button
        ContinueButton       matlab.ui.control.Button
        TonerButton          matlab.ui.control.Button
        CleanserButton       matlab.ui.control.Button
        EyeCreamButton       matlab.ui.control.Button
        MoisturiserButton    matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        NowexplainwhatyourelookingforLabel  matlab.ui.control.Label
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

     %% Product Recommendation App
    % This app takes the user's age, skin type, skin quirks from the
    % UserSkinInformation app and then asks the user for their
    % target skincare goal, preferred product type, and budget. 
    
    % It uses these six variables to filter a CSV dataset of skincare 
    % products and to return a list personalised product recommendations 
    % and pass it to the final screen. The final screen will either show 
    % the user the top 3 product recommendations, sorted by review counts 
    % to find most popular, or take them to a failed screen, telling the 
    % user that their combination of skin info has filtered out every
    % product.


    properties (Access = private)
        % These properties store user-provided inputs
        age % User's age
        skinType % User's skin type
        skinQuirks % Specific skin concerns
        productType % Type of product the user is interested in
        target % Desired benefit or skincare outcome
        budget % Maximum budget the user is willing to spend
        
    end
    
    methods (Access = public)
        
        % This method receives user info passed from the previous app
        function startupFcn (app, age, skinQuirks, skinType)
            app.age = age;
            app.skinQuirks = skinQuirks;
            app.skinType = skinType; 
        end
    end
    
    methods (Access = public)
        
        % Main function that processes the data and filters product results
        function runReadingCSV(app)
           
            % Read the file and use the previously developed file to
            % clean the skincare product dataset from CSV
            filePath = "/Users/lilytwelftree/Documents/Uni/MATLAB/Project/Progress-11/Cleaned_ProductDetails.csv";
            products = cleanData2(filePath);
            

            % Convert user's age into a broad age group category.
            % This grouping is used to tailor product recommendations based on 
            % common skincare needs and ingredient tolerances for each life stage.
            if app.age <= 19
                ageGroup = 'Teen';
            elseif app.age <= 29
                ageGroup = 'Twenties';
            elseif app.age <= 39
                ageGroup = 'Thirties';
            elseif app.age <= 49
                ageGroup = 'Forties';
            else
                ageGroup = 'FiftyPlus';
            end
            
        
            % Call GroupingConcerns to get a list of potentially harmful ingredients 
            % based on the user's age group, skin type, and skin quirks. Ignore the 
            % first output (goodIngredients, as they won't be used in filter)
            % using ~ and store only the list of bad ingredients.
            
            [~, badIngredients] = GroupingConcerns(ageGroup, app.skinType, app.skinQuirks);
            
            % If badIngredients exist, create a logical array identifying products 
            % that are explicitly 'made without' any of those ingredients. 
            % If no badIngredients are specified (e.g. the user has 'normal' skin 
            % and no specific concerns), allow all products to pass this filter.
            
            if ~isempty(badIngredients)
                noBadInMadeWithout = contains(products.MadeWithout, badIngredients);
            else
                noBadInMadeWithout = true(height(products), 1);
            end
            
            % Apply all relevant filters to the product list:
            % - products must be within the user's specified budget,
            % - match the desired product type,
            % - address the user’s selected skin concern (target),
            % - and not contain any unwanted (bad) ingredients.

            filtered_products = products(products.Price <= app.budget & ...
                contains(products.Subcategory, app.productType) &...
                contains(products.Benefits, app.target) & ...
                noBadInMadeWithout,:);
            
            % In many cases this list of recommended products is quite long
            % so to sort them by most popular to find those with best
            % social proof. Sorted by ReviewCount (descending), 
            % then Rating (descending).
            filtered_products = sortrows(filtered_products, {'ReviewCount', 'Rating'}, {'descend', 'descend'});
 
            
            % Before going to the final app screen, we check if there are 
            % any products that match the user's skin information and
            % requirements.
            if height(filtered_products) < 1
                % If there are no products, the user is taken to 
                % FailedScreen where they are prompted to either reselect
                % or exit the app
                failedScreenApp = FailedScreen();  
                delete(app);  % Close the current app
                % Exit the function early so that the app showing top 
                % products is not run.
                return;
            end

            % Let the user know that the app has all the inputs needed 
            disp('Great! That is all we need to know. Loading your top recomendations now');
            
            % load the final app and pass through the products that have
            % passed all the filters
            showTableApp = ShowTable();
            showTableApp.startupFcn(filtered_products);
            
            delete(app) % Close the current app
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoisturiserButton
        function MoisturiserButtonPushed(app, event)
            %change colour to signal pressed
            app.MoisturiserButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Moisturiser';
        end

        % Button pushed function: EyeCreamButton
        function EyeCreamButtonPushed(app, event)
            %change colour to signal pressed
            app.EyeCreamButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Eye Cream';
        end

        % Button pushed function: CleanserButton
        function CleanserButtonPushed(app, event)
            %change colour to signal pressed
            app.CleanserButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Cleanser';
        end

        % Button pushed function: TonerButton
        function TonerButtonPushed(app, event)
            %change colour to signal pressed
            app.TonerButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Toner';
        end

        % Button pushed function: SPFButton
        function SPFButtonPushed(app, event)
            %change colour to signal pressed
            app.SPFButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'SPF';
        end

        % Button pushed function: TreatmentButton
        function TreatmentButtonPushed(app, event)
            %change colour to signal pressed
            app.TreatmentButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Treatment';
        end

        % Button pushed function: LipCareButton
        function LipCareButtonPushed(app, event)
            %change colour to signal pressed
            app.LipCareButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Type that the user interested in
            app.productType = 'Lip Care';
        end

        % Button pushed function: AgingButton
        function AgingButtonPushed(app, event)
            %change colour to signal pressed
            app.AgingButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Firming';
        end

        % Button pushed function: PuffinessButton
        function PuffinessButtonPushed(app, event)
            %change colour to signal pressed
            app.PuffinessButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Plumping';
        end

        % Button pushed function: TextureButton
        function TextureButtonPushed(app, event)
            %change colour to signal pressed
            app.TextureButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Smoothing';
        end

        % Button pushed function: DehydrationButton
        function DehydrationButtonPushed(app, event)
            %change colour to signal pressed
            app.DehydrationButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Hydrating';
        end

        % Button pushed function: AcneBreakoutsButton
        function AcneBreakoutsButtonPushed(app, event)
            %change colour to signal pressed
            app.AcneBreakoutsButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Anti-blemish';
        end

        % Button pushed function: DullnessButton
        function DullnessButtonPushed(app, event)
            %change colour to signal pressed
            app.DullnessButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Brightening';
        end

        % Button pushed function: UnevenToneButton
        function UnevenToneButtonPushed(app, event)
            %change colour to signal pressed
            app.UnevenToneButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Product Target that the user wants to adress
            app.target = 'Exfoliating';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            %change colour to signal pressed
            app.ContinueButton.BackgroundColor = [0.50, 0.50, 0.50]; %dark grey
            drawnow; % Immediately update UI
            
            
            %Call the runReadingCSV function to take user inputs and
            %apply filtering.
            app.runReadingCSV();

            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.budget = floor(app.Slider_2.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NowexplainwhatyourelookingforLabel = uilabel(app.UIFigure);
            app.NowexplainwhatyourelookingforLabel.Text = {'Now, explain what'; 'you''re looking for'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'What type of product?';
            app.MoisturiserButton = uibutton(app.UIFigure, 'push');
            app.MoisturiserButton.ButtonPushedFcn = createCallbackFcn(app, @MoisturiserButtonPushed, true);
            app.MoisturiserButton.Text = 'Moisturiser';
            app.EyeCreamButton = uibutton(app.UIFigure, 'push');
            app.EyeCreamButton.ButtonPushedFcn = createCallbackFcn(app, @EyeCreamButtonPushed, true);
            app.EyeCreamButton.Text = 'Eye Cream';
            app.CleanserButton = uibutton(app.UIFigure, 'push');
            app.CleanserButton.ButtonPushedFcn = createCallbackFcn(app, @CleanserButtonPushed, true);
            app.CleanserButton.Text = 'Cleanser';
            app.TonerButton = uibutton(app.UIFigure, 'push');
            app.TonerButton.ButtonPushedFcn = createCallbackFcn(app, @TonerButtonPushed, true);
            app.TonerButton.Text = 'Toner';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.SPFButton = uibutton(app.UIFigure, 'push');
            app.SPFButton.ButtonPushedFcn = createCallbackFcn(app, @SPFButtonPushed, true);
            app.SPFButton.Text = 'SPF';
            app.TreatmentButton = uibutton(app.UIFigure, 'push');
            app.TreatmentButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentButtonPushed, true);
            app.TreatmentButton.Text = 'Treatment';
            app.LipCareButton = uibutton(app.UIFigure, 'push');
            app.LipCareButton.ButtonPushedFcn = createCallbackFcn(app, @LipCareButtonPushed, true);
            app.LipCareButton.Text = 'Lip Care';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'What do you want to target?';
            app.AgingButton = uibutton(app.UIFigure, 'push');
            app.AgingButton.ButtonPushedFcn = createCallbackFcn(app, @AgingButtonPushed, true);
            app.AgingButton.Text = 'Aging';
            app.PuffinessButton = uibutton(app.UIFigure, 'push');
            app.PuffinessButton.ButtonPushedFcn = createCallbackFcn(app, @PuffinessButtonPushed, true);
            app.PuffinessButton.Text = 'Puffiness';
            app.TextureButton = uibutton(app.UIFigure, 'push');
            app.TextureButton.ButtonPushedFcn = createCallbackFcn(app, @TextureButtonPushed, true);
            app.TextureButton.Text = 'Texture';
            app.AcneBreakoutsButton = uibutton(app.UIFigure, 'push');
            app.AcneBreakoutsButton.ButtonPushedFcn = createCallbackFcn(app, @AcneBreakoutsButtonPushed, true);
            app.AcneBreakoutsButton.Text = 'Acne & Breakouts';
            app.DullnessButton = uibutton(app.UIFigure, 'push');
            app.DullnessButton.ButtonPushedFcn = createCallbackFcn(app, @DullnessButtonPushed, true);
            app.DullnessButton.Text = 'Dullness';
            app.UnevenToneButton = uibutton(app.UIFigure, 'push');
            app.UnevenToneButton.ButtonPushedFcn = createCallbackFcn(app, @UnevenToneButtonPushed, true);
            app.UnevenToneButton.Text = 'Uneven Tone';
            app.DehydrationButton = uibutton(app.UIFigure, 'push');
            app.DehydrationButton.ButtonPushedFcn = createCallbackFcn(app, @DehydrationButtonPushed, true);
            app.DehydrationButton.Text = 'Dehydration';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'What is the most you''re willing to spend?';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 200];
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProductPreferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end