classdef ProductPreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider_2             matlab.ui.control.Slider
        Label_4              matlab.ui.control.Label
        DehydrationButton    matlab.ui.control.Button
        UnevenToneButton     matlab.ui.control.Button
        DullnessButton       matlab.ui.control.Button
        AcneBreakoutsButton  matlab.ui.control.Button
        TextureButton        matlab.ui.control.Button
        PuffinessButton      matlab.ui.control.Button
        AgingButton          matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        LipCareButton        matlab.ui.control.Button
        TreatmentButton      matlab.ui.control.Button
        ContinueButton       matlab.ui.control.Button
        TonerButton          matlab.ui.control.Button
        CleanserButton       matlab.ui.control.Button
        EyeCreamButton       matlab.ui.control.Button
        MoisturiserButton    matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        NowexplainwhatyourelookingforLabel  matlab.ui.control.Label
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        age 
        skinType
        skinQuirks
        productType
        target
        budget
        
    end
    
    methods (Access = public)
        
        function startupFcn (app, age, skinQuirks, skinType)
            app.age = age;
            app.skinQuirks = skinQuirks;
            app.skinType = skinType; 
        end
    end
    
    methods (Access = public)
        
        function results = runReadingCSV(app)
            app.age = app.age;
            app.skinType = app.skinType;
            app.skinQuirks = app.skinQuirks;
            app.target = app.target;
            app.productType = app.productType;
            budget = app.budget;

            % Read Product CSV
            filePath = '/Users/lilytwelftree/Documents/Uni/MATLAB/Project/Progress-10_Re-collectData/ProductDetailsWithImages.csv';
            products = cleanData2(filePath);
            
            % Determine age group
            if app.age <= 19
                ageGroup = 'Teen';
            elseif app.age <= 29
                ageGroup = 'Twenties';
            elseif app.age <= 39
                ageGroup = 'Thirties';
            elseif app.age <= 49
                ageGroup = 'Forties';
            else
                ageGroup = 'FiftyPlus';
            end
            
            %disp(app.skinQuirks);
            disp(['Age: ' app.productType]);
            disp(['Target: ' app.target]);
            disp(['Budget: ' num2str(app.budget)]);
            
            % Pass historical user info into GroupingConcerns function to collect full
            % list of good and bad ingredients for each variable
            
            [~, badIngredients] = GroupingConcerns(ageGroup, app.skinType, app.skinQuirks);
            
            % Check that bad ingredients are not present in either field
            noBadInIngredients = ~contains(products.KeyIngredients, badIngredients);
            noBadInMadeWithout = contains(products.MadeWithout, badIngredients);
            
            % Include only rows where bad ingredients are NOT mentioned in either
            madeWithout = noBadInIngredients & noBadInMadeWithout;

            
            % Apply relevant filters to product CSV
            filtered_products = products(products.Price <= app.budget & ...
                contains(products.Subcategory, app.productType) &...
                contains(products.Benefits, app.target) & ...
                madeWithout,:);
            
            % Sort by ReviewCount (descending), then Rating (descending)
            filtered_products = sortrows(filtered_products, {'ReviewCount', 'Rating'}, {'descend', 'descend'});
            disp(filtered_products);
            
            % Check if the filtered dataset is empty
            if height(filtered_products) < 1
                % If there are no products, go to FailedScreen
                failedScreenApp = FailedScreen();  % Make sure FailedScreen.mlapp exists
                delete(app.UIFigure);  % Close the current app
                return;  % Exit the function early
            end
            
            % If there are one or more products, create and display the ShowTable app
            showTableApp = ShowTable();
            showTableApp.startupFcn(filtered_products);
            
            % Close the current app
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoisturiserButton
        function MoisturiserButtonPushed(app, event)
            %change colour to signal pressed
            app.MoisturiserButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Moisturiser';
        end

        % Button pushed function: EyeCreamButton
        function EyeCreamButtonPushed(app, event)
            %change colour to signal pressed
            app.EyeCreamButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Eye Cream';
        end

        % Button pushed function: CleanserButton
        function CleanserButtonPushed(app, event)
            %change colour to signal pressed
            app.CleanserButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Cleanser';
        end

        % Button pushed function: TonerButton
        function TonerButtonPushed(app, event)
            %change colour to signal pressed
            app.TonerButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Toner';
        end

        % Callback function: not associated with a component
        function SPFButtonPushed(app, event)
            %change colour to signal pressed
            app.SPFButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'SPF';
        end

        % Button pushed function: TreatmentButton
        function TreatmentButtonPushed(app, event)
            %change colour to signal pressed
            app.TreatmentButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Treatment';
        end

        % Button pushed function: LipCareButton
        function LipCareButtonPushed(app, event)
            %change colour to signal pressed
            app.LipCareButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.productType = 'Lip Care';
        end

        % Button pushed function: AgingButton
        function AgingButtonPushed(app, event)
            %change colour to signal pressed
            app.AgingButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Firming';
        end

        % Button pushed function: PuffinessButton
        function PuffinessButtonPushed(app, event)
            %change colour to signal pressed
            app.PuffinessButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Puffiness';
        end

        % Button pushed function: TextureButton
        function TextureButtonPushed(app, event)
            %change colour to signal pressed
            app.TextureButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Smoothing';
        end

        % Button pushed function: DehydrationButton
        function DehydrationButtonPushed(app, event)
            %change colour to signal pressed
            app.DehydrationButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Hydrating';
        end

        % Button pushed function: AcneBreakoutsButton
        function AcneBreakoutsButtonPushed(app, event)
            %change colour to signal pressed
            app.AcneBreakoutsButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Anti-blemish';
        end

        % Button pushed function: DullnessButton
        function DullnessButtonPushed(app, event)
            %change colour to signal pressed
            app.DullnessButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Brightening';
        end

        % Button pushed function: UnevenToneButton
        function UnevenToneButtonPushed(app, event)
            %change colour to signal pressed
            app.UnevenToneButton.BackgroundColor = [1.00, 0.76, 0.85];
            drawnow;
            
            
            app.target = 'Exfoliating';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            %change colour to signal pressed
            app.ContinueButton.BackgroundColor = [0.50, 0.50, 0.50];
            drawnow;
            
            
            %print to show what is being saved
            app.runReadingCSV();

            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.budget = floor(app.Slider_2.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NowexplainwhatyourelookingforLabel = uilabel(app.UIFigure);
            app.NowexplainwhatyourelookingforLabel.Text = {'Now, explain what'; 'you''re looking for'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'What type of product?';
            app.MoisturiserButton = uibutton(app.UIFigure, 'push');
            app.MoisturiserButton.ButtonPushedFcn = createCallbackFcn(app, @MoisturiserButtonPushed, true);
            app.MoisturiserButton.Text = 'Moisturiser';
            app.EyeCreamButton = uibutton(app.UIFigure, 'push');
            app.EyeCreamButton.ButtonPushedFcn = createCallbackFcn(app, @EyeCreamButtonPushed, true);
            app.EyeCreamButton.Text = 'Eye Cream';
            app.CleanserButton = uibutton(app.UIFigure, 'push');
            app.CleanserButton.ButtonPushedFcn = createCallbackFcn(app, @CleanserButtonPushed, true);
            app.CleanserButton.Text = 'Cleanser';
            app.TonerButton = uibutton(app.UIFigure, 'push');
            app.TonerButton.ButtonPushedFcn = createCallbackFcn(app, @TonerButtonPushed, true);
            app.TonerButton.Text = 'Toner';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.TreatmentButton = uibutton(app.UIFigure, 'push');
            app.TreatmentButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentButtonPushed, true);
            app.TreatmentButton.Text = 'Treatment';
            app.LipCareButton = uibutton(app.UIFigure, 'push');
            app.LipCareButton.ButtonPushedFcn = createCallbackFcn(app, @LipCareButtonPushed, true);
            app.LipCareButton.Text = 'Lip Care';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'What do you want to target?';
            app.AgingButton = uibutton(app.UIFigure, 'push');
            app.AgingButton.ButtonPushedFcn = createCallbackFcn(app, @AgingButtonPushed, true);
            app.AgingButton.Text = 'Aging';
            app.PuffinessButton = uibutton(app.UIFigure, 'push');
            app.PuffinessButton.ButtonPushedFcn = createCallbackFcn(app, @PuffinessButtonPushed, true);
            app.PuffinessButton.Text = 'Puffiness';
            app.TextureButton = uibutton(app.UIFigure, 'push');
            app.TextureButton.ButtonPushedFcn = createCallbackFcn(app, @TextureButtonPushed, true);
            app.TextureButton.Text = 'Texture';
            app.AcneBreakoutsButton = uibutton(app.UIFigure, 'push');
            app.AcneBreakoutsButton.ButtonPushedFcn = createCallbackFcn(app, @AcneBreakoutsButtonPushed, true);
            app.AcneBreakoutsButton.Text = 'Acne & Breakouts';
            app.DullnessButton = uibutton(app.UIFigure, 'push');
            app.DullnessButton.ButtonPushedFcn = createCallbackFcn(app, @DullnessButtonPushed, true);
            app.DullnessButton.Text = 'Dullness';
            app.UnevenToneButton = uibutton(app.UIFigure, 'push');
            app.UnevenToneButton.ButtonPushedFcn = createCallbackFcn(app, @UnevenToneButtonPushed, true);
            app.UnevenToneButton.Text = 'Uneven Tone';
            app.DehydrationButton = uibutton(app.UIFigure, 'push');
            app.DehydrationButton.ButtonPushedFcn = createCallbackFcn(app, @DehydrationButtonPushed, true);
            app.DehydrationButton.Text = 'Dehydration';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'What is the most you''re willing to spend?';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 200];
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProductPreferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end