classdef ProductSpecifications < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider               matlab.ui.control.RangeSlider
        Label_4              matlab.ui.control.Label
        DehydrationButton    matlab.ui.control.Button
        UnevenToneButton     matlab.ui.control.Button
        DullnessButton       matlab.ui.control.Button
        AcneBreakoutsButton  matlab.ui.control.Button
        TextureButton        matlab.ui.control.Button
        PuffinessButton      matlab.ui.control.Button
        AgingButton          matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        LipCareButton        matlab.ui.control.Button
        TreatmentButton      matlab.ui.control.Button
        SPFButton            matlab.ui.control.Button
        ContinueButton       matlab.ui.control.Button
        TonerButton          matlab.ui.control.Button
        CleanserButton       matlab.ui.control.Button
        EyeCreamButton       matlab.ui.control.Button
        MoisturiserButton    matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        NowexplainwhatyourelookingforLabel  matlab.ui.control.Label
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        age 
        skinType
        skinQuirks
        productType
        target
        budgetRange
    end
    
    methods (Access = public)
        
        function startupFcn (app, age, skinQuirks, skinType)
            app.age = age;
            app.skinQuirks = skinQuirks;
            app.skinType = skinType; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoisturiserButton
        function MoisturiserButtonPushed(app, event)
            app.productType = 'Moisturiser';
        end

        % Button pushed function: EyeCreamButton
        function EyeCreamButtonPushed(app, event)
            app.productType = 'Eye Cream';
        end

        % Button pushed function: CleanserButton
        function CleanserButtonPushed(app, event)
            app.productType = 'Cleanser';
        end

        % Button pushed function: TonerButton
        function TonerButtonPushed(app, event)
            app.productType = 'Toner';
        end

        % Button pushed function: SPFButton
        function SPFButtonPushed(app, event)
            app.productType = 'SPF';
        end

        % Button pushed function: TreatmentButton
        function TreatmentButtonPushed(app, event)
            app.productType = 'Treatment';
        end

        % Button pushed function: LipCareButton
        function LipCareButtonPushed(app, event)
            app.productType = 'Lip Care';
        end

        % Button pushed function: AgingButton
        function AgingButtonPushed(app, event)
            app.target = 'Firming';
        end

        % Button pushed function: PuffinessButton
        function PuffinessButtonPushed(app, event)
            app.target = 'Puffiness';
        end

        % Button pushed function: TextureButton
        function TextureButtonPushed(app, event)
            app.target = 'Smoothing';
        end

        % Button pushed function: DehydrationButton
        function DehydrationButtonPushed(app, event)
            app.target = 'Hydrating';
        end

        % Button pushed function: AcneBreakoutsButton
        function AcneBreakoutsButtonPushed(app, event)
            app.target = 'Anti-blemish';
        end

        % Button pushed function: DullnessButton
        function DullnessButtonPushed(app, event)
            app.target = 'Brightening';
        end

        % Button pushed function: UnevenToneButton
        function UnevenToneButtonPushed(app, event)
            app.target = 'Exfoliating';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.budgetRange = app.Slider.Value;
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            %print to show what is being saved
            disp(['Budget: ' num2str(app.budgetRange)]);
            disp(['Product Type: ' app.productType])
            %disp(['Budget: ' app.budgetRange]);
            disp(['Product Target: ' app.target]);

            %Close current window
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NowexplainwhatyourelookingforLabel = uilabel(app.UIFigure);
            app.NowexplainwhatyourelookingforLabel.Text = {'Now, explain what'; 'you''re looking for'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'What type of product?';
            app.MoisturiserButton = uibutton(app.UIFigure, 'push');
            app.MoisturiserButton.ButtonPushedFcn = createCallbackFcn(app, @MoisturiserButtonPushed, true);
            app.MoisturiserButton.Text = 'Moisturiser';
            app.EyeCreamButton = uibutton(app.UIFigure, 'push');
            app.EyeCreamButton.ButtonPushedFcn = createCallbackFcn(app, @EyeCreamButtonPushed, true);
            app.EyeCreamButton.Text = 'Eye Cream';
            app.CleanserButton = uibutton(app.UIFigure, 'push');
            app.CleanserButton.ButtonPushedFcn = createCallbackFcn(app, @CleanserButtonPushed, true);
            app.CleanserButton.Text = 'Cleanser';
            app.TonerButton = uibutton(app.UIFigure, 'push');
            app.TonerButton.ButtonPushedFcn = createCallbackFcn(app, @TonerButtonPushed, true);
            app.TonerButton.Text = 'Toner';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.SPFButton = uibutton(app.UIFigure, 'push');
            app.SPFButton.ButtonPushedFcn = createCallbackFcn(app, @SPFButtonPushed, true);
            app.SPFButton.Text = 'SPF';
            app.TreatmentButton = uibutton(app.UIFigure, 'push');
            app.TreatmentButton.ButtonPushedFcn = createCallbackFcn(app, @TreatmentButtonPushed, true);
            app.TreatmentButton.Text = 'Treatment';
            app.LipCareButton = uibutton(app.UIFigure, 'push');
            app.LipCareButton.ButtonPushedFcn = createCallbackFcn(app, @LipCareButtonPushed, true);
            app.LipCareButton.Text = 'Lip Care';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'What do you want to target?';
            app.AgingButton = uibutton(app.UIFigure, 'push');
            app.AgingButton.ButtonPushedFcn = createCallbackFcn(app, @AgingButtonPushed, true);
            app.AgingButton.Text = 'Aging';
            app.PuffinessButton = uibutton(app.UIFigure, 'push');
            app.PuffinessButton.ButtonPushedFcn = createCallbackFcn(app, @PuffinessButtonPushed, true);
            app.PuffinessButton.Text = 'Puffiness';
            app.TextureButton = uibutton(app.UIFigure, 'push');
            app.TextureButton.ButtonPushedFcn = createCallbackFcn(app, @TextureButtonPushed, true);
            app.TextureButton.Text = 'Texture';
            app.AcneBreakoutsButton = uibutton(app.UIFigure, 'push');
            app.AcneBreakoutsButton.ButtonPushedFcn = createCallbackFcn(app, @AcneBreakoutsButtonPushed, true);
            app.AcneBreakoutsButton.Text = 'Acne & Breakouts';
            app.DullnessButton = uibutton(app.UIFigure, 'push');
            app.DullnessButton.ButtonPushedFcn = createCallbackFcn(app, @DullnessButtonPushed, true);
            app.DullnessButton.Text = 'Dullness';
            app.UnevenToneButton = uibutton(app.UIFigure, 'push');
            app.UnevenToneButton.ButtonPushedFcn = createCallbackFcn(app, @UnevenToneButtonPushed, true);
            app.UnevenToneButton.Text = 'Uneven Tone';
            app.DehydrationButton = uibutton(app.UIFigure, 'push');
            app.DehydrationButton.ButtonPushedFcn = createCallbackFcn(app, @DehydrationButtonPushed, true);
            app.DehydrationButton.Text = 'Dehydration';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'What is your budget?';
            app.Slider = uislider(app.UIFigure, 'range');
            app.Slider.Limits = [0 200];
            app.Slider.MajorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100 102 104 106 108 110 112 114 116 118 120 122 124 126 128 130 132 134 136 138 140 142 144 146 148 150 152 154 156 158 160 162 164 166 168 170 172 174 176 178 180 182 184 186 188 190 192 194 196 198 200];
            app.Slider.Value = [70 130];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProductSpecifications

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end