classdef ShowTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CheckitoutButton_3      matlab.ui.control.Button
        CheckitoutButton_2      matlab.ui.control.Button
        CheckitoutButton        matlab.ui.control.Button
        Label_7                 matlab.ui.control.Label
        NUMBERTHREELabel        matlab.ui.control.Label
        NUMBERTWOLabel          matlab.ui.control.Label
        NUMBERONELabel          matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ExitButton              matlab.ui.control.Button
        TakethequizagainButton  matlab.ui.control.Button
        ThesearethebestproductsforyourskinLabel  matlab.ui.control.Label
    end

    %% Display Top 3 recommendations


    properties (Access = private)
        ProductData table % Description
    end
    
    methods (Access = public)
        
        % Information loaded into app before any user activity/
        function startupFcn(app, productData)
            % This function runs when the app starts. It receives a table of 
            % product recommendations and displays up to 3 of them. 
    
            % Create lists of UI components (labels and buttons) used to display
            % product information. 
            productNameLabels = {app.Label, app.Label_2, app.Label_3};
            brandLabels = {app.Label_4, app.Label_5, app.Label_6};
            hyperlinks = {app.CheckitoutButton, app.CheckitoutButton_2, app.CheckitoutButton_3};
            
            % Get number of products to show (up to 3)
            numToShow = min(3, height(productData));
            
            % Loop through the top products and update the UI 
            % labels/buttons to fill with product information
            for i = 1:numToShow
                productNameLabels{i}.Text = productData.ProductName{i};
                brandLabels{i}.Text = productData.Brand{i};

                % Set button to open product link
                productURL = productData.Link{i};
                hyperlinks{i}.Enable = 'on';
                % opens browser when hylerlink clciked
                hyperlinks{i}.ButtonPushedFcn = @(btn, event) web(productURL, '-browser');
            end
            
            % placeholders if there are fewer than 3 products
            for i = numToShow+1:3
                productNameLabels{i}.Text = "You don't have any more reccomendations!";
                brandLabels{i}.Text = "";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            %Closes app when user selects to exit
            delete(app);
        end

        % Button pushed function: TakethequizagainButton
        function TakethequizagainButtonPushed(app, event)
            %Sends the user back to the start of the app, skipping the
            %homescreen, if they want to take the quiz again
            newApp = UserSkinInformation();

            %deletes the final product slide.
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThesearethebestproductsforyourskinLabel = uilabel(app.UIFigure);
            app.ThesearethebestproductsforyourskinLabel.Text = {'These are the best '; 'products for your skin!'};
            app.TakethequizagainButton = uibutton(app.UIFigure, 'push');
            app.TakethequizagainButton.ButtonPushedFcn = createCallbackFcn(app, @TakethequizagainButtonPushed, true);
            app.TakethequizagainButton.Text = 'Take the quiz again';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.NUMBERONELabel = uilabel(app.UIFigure);
            app.NUMBERONELabel.Text = 'NUMBER ONE:';
            app.NUMBERTWOLabel = uilabel(app.UIFigure);
            app.NUMBERTWOLabel.Text = 'NUMBER TWO:';
            app.NUMBERTHREELabel = uilabel(app.UIFigure);
            app.NUMBERTHREELabel.Text = 'NUMBER THREE:';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Plus, we sorted them to find the products people love the most.';
            app.CheckitoutButton = uibutton(app.UIFigure, 'push');
            app.CheckitoutButton.Text = 'Check it out';
            app.CheckitoutButton_2 = uibutton(app.UIFigure, 'push');
            app.CheckitoutButton_2.Text = 'Check it out';
            app.CheckitoutButton_3 = uibutton(app.UIFigure, 'push');
            app.CheckitoutButton_3.Text = 'Check it out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end