classdef UserSkinInformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NoneButton           matlab.ui.control.Button
        EditField            matlab.ui.control.NumericEditField
        ContinueButton       matlab.ui.control.Button
        EczemaButton         matlab.ui.control.Button
        RosaceaButton        matlab.ui.control.Button
        PsoriasisButton      matlab.ui.control.Button
        DrynessButton        matlab.ui.control.Button
        AcneProneButton      matlab.ui.control.Button
        SensitiveButton      matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        OilyButton           matlab.ui.control.Button
        CombinationButton    matlab.ui.control.Button
        DryButton            matlab.ui.control.Button
        NormalButton         matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        TellusaboutyouLabel  matlab.ui.control.Label
    end

    
    %% Historical Skin info App
    % This app asks the user for information about their skin to understand
    % their current skincare needs and preferences. The user provides their:
    % - Age (via slider or text input)
    % - Skin type (e.g., Normal, Dry, Oily, Combination)
    % - Skin concerns (e.g., Acne-Prone, Eczema, Rosacea, Psoriasis)
    %
    % The app captures these inputs and stores them in accessible properties.
    % Once the user confirms their selections by clicking the "Continue" button,
    % the data is passed to the ProductPreferences app to help generate
    % ingredient and product recommendations tailored to their profile.
    %
    % The UI also provides visual feedback (via colour changes) when selections
    % are made, enhancing usability and clarity.

    properties (Access = public)
        %Store user input values to be passed to other components or functions
        age %User's age
        skinType %User's selected skin type 
        skinQuirks %User's selected skinQuirk 
    end

    % Each of the following functions handles what happens when a specific button is pressed.
    % They record the user's input and change the button colour as a visual signal of selection.


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
            %change the button colour to signal its been pressed. 
            app.NormalButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Type
            app.skinType = 'Normal';
        end

        % Button pushed function: DryButton
        function DryButtonPushed(app, event)
            %change the button colour to signal its been pressed. 
            app.DryButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Type
            app.skinType = 'Dry';
        end

        % Button pushed function: CombinationButton
        function CombinationButtonPushed(app, event)
            %change the button colour to signal its been pressed. 
            app.CombinationButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Type
            app.skinType = 'Combination';
        end

        % Button pushed function: OilyButton
        function OilyButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.OilyButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Type
            app.skinType = 'Oily';
        end

        % Button pushed function: SensitiveButton
        function SensitiveButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.SensitiveButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'Sensitive';
        end

        % Button pushed function: AcneProneButton
        function AcneProneButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.AcneProneButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'AcneProne';
        end

        % Button pushed function: DrynessButton
        function DrynessButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.DrynessButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'Dryness';
        end

        % Button pushed function: PsoriasisButton
        function PsoriasisButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.PsoriasisButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'Psoriasis';
        end

        % Button pushed function: RosaceaButton
        function RosaceaButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.RosaceaButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'Rosacea';
        end

        % Button pushed function: EczemaButton
        function EczemaButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.EczemaButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition)
            app.skinQuirks = 'Eczema';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            %change the button colour to signal its been pressed.
            app.ContinueButton.BackgroundColor = [0.50, 0.50, 0.50]; %dark grey
            drawnow; % Immediately update UI
            
            % Let the user know in the consol, where we are up to in the
            % app
            disp('We now have you skincare information! Now we will ask about your product preferences');
            
            % Launch the next app (ProductPreferences) and pass in user selections
            openProductPreferences = ProductPreferences();
            openProductPreferences.startupFcn(app.age, app.skinQuirks, app.skinType);

            %Close this current app window
            delete(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %Collects the users exact age and save to app.
            app.age = app.EditField.Value;
        end

        % Button pushed function: NoneButton
        function NoneButtonPushed(app, event)
            %change colour to signal pressed
            app.NoneButton.BackgroundColor = [1.00, 0.76, 0.85]; %light pink
            drawnow; % Immediately update UI
            
            % Save Skin Quirk (skin condition) button for no skin condition
            % rather than having a blank variables
            app.skinQuirks = 'Blank';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TellusaboutyouLabel = uilabel(app.UIFigure);
            app.TellusaboutyouLabel.Text = {'Tell us'; 'about you!'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'How old are you?';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'What is your skin type?';
            app.NormalButton = uibutton(app.UIFigure, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.DryButton = uibutton(app.UIFigure, 'push');
            app.DryButton.ButtonPushedFcn = createCallbackFcn(app, @DryButtonPushed, true);
            app.DryButton.Text = 'Dry';
            app.CombinationButton = uibutton(app.UIFigure, 'push');
            app.CombinationButton.ButtonPushedFcn = createCallbackFcn(app, @CombinationButtonPushed, true);
            app.CombinationButton.Text = 'Combination';
            app.OilyButton = uibutton(app.UIFigure, 'push');
            app.OilyButton.ButtonPushedFcn = createCallbackFcn(app, @OilyButtonPushed, true);
            app.OilyButton.Text = 'Oily';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Does your skin have any quirks?';
            app.SensitiveButton = uibutton(app.UIFigure, 'push');
            app.SensitiveButton.ButtonPushedFcn = createCallbackFcn(app, @SensitiveButtonPushed, true);
            app.SensitiveButton.Text = 'Sensitive';
            app.AcneProneButton = uibutton(app.UIFigure, 'push');
            app.AcneProneButton.ButtonPushedFcn = createCallbackFcn(app, @AcneProneButtonPushed, true);
            app.AcneProneButton.Text = 'Acne Prone';
            app.DrynessButton = uibutton(app.UIFigure, 'push');
            app.DrynessButton.ButtonPushedFcn = createCallbackFcn(app, @DrynessButtonPushed, true);
            app.DrynessButton.Text = 'Dryness';
            app.PsoriasisButton = uibutton(app.UIFigure, 'push');
            app.PsoriasisButton.ButtonPushedFcn = createCallbackFcn(app, @PsoriasisButtonPushed, true);
            app.PsoriasisButton.Text = 'Psoriasis';
            app.RosaceaButton = uibutton(app.UIFigure, 'push');
            app.RosaceaButton.ButtonPushedFcn = createCallbackFcn(app, @RosaceaButtonPushed, true);
            app.RosaceaButton.Text = 'Rosacea';
            app.EczemaButton = uibutton(app.UIFigure, 'push');
            app.EczemaButton.ButtonPushedFcn = createCallbackFcn(app, @EczemaButtonPushed, true);
            app.EczemaButton.Text = 'Eczema';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.NoneButton = uibutton(app.UIFigure, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @NoneButtonPushed, true);
            app.NoneButton.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserSkinInformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end